classdef TrackDataApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        Menu2                matlab.ui.container.Menu
        TabGroup             matlab.ui.container.TabGroup
        EngineTab            matlab.ui.container.Tab
        GaugeLabel           matlab.ui.control.Label
        Gauge                matlab.ui.control.Gauge
        ImportDataButton     matlab.ui.control.Button
        TestGraphsTab        matlab.ui.container.Tab
        CSVLINESpinnerLabel  matlab.ui.control.Label
        CSVLINESpinner       matlab.ui.control.Spinner
        UIAxes               matlab.ui.control.UIAxes
        LapDropDownLabel     matlab.ui.control.Label
        LapDropDown          matlab.ui.control.DropDown
    end


    properties (Access = private)
    end

    methods (Access = private)
    
        
        function results = Startup(app)
            data = uiimport('-file');
%             h = msgbox('CLICK "OK" ONCE DONE IMPORTING');
%             while ishandle(h)
%                  
%                  if ishandle(h) == 0
%                     msgbox('Program ended');
                    Lat = rmmissing(data.x_Latitude___Degrees____180_0_180_0_50_');
                    Long = rmmissing(data.x_Longitude___Degrees____180_0_180_0_50_');
                    Speed = rmmissing(data.x_Speed___MPH___0_0_150_0_50_');
                    colormap(app.UIAxes,hsv);
                    scatter(app.UIAxes,Lat(10000:21000),Long(10000:21000),20,Speed(10000:21000))
                    return;
%                  end
%             end
            
        end
        
    end


    methods (Access = private)

        % Size changed function: TestGraphsTab
        function TestGraphsTabSizeChanged(app, event)

        end

        % Value changing function: CSVLINESpinner
        function CSVLINESpinnerValueChanging(app, event)
            changingValue = event.Value;
            val = app.CSVLINESpinner.Value;
            app.Gauge.Value = changingValue;
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            Startup(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EngineTab = uitab(app.TabGroup);
            app.EngineTab.Title = 'Engine';
            app.GaugeLabel = uilabel(app.EngineTab);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.EngineTab, 'circular');
            app.Gauge.Limits = [0 14000];
            app.Gauge.MajorTickLabels = {'0', '1400', '2800', '4200', '5600', '7000', '8400', '9800', '11200', '12600', '14000'};
            app.ImportDataButton = uibutton(app.EngineTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.TestGraphsTab = uitab(app.TabGroup);
            app.TestGraphsTab.SizeChangedFcn = createCallbackFcn(app, @TestGraphsTabSizeChanged, true);
            app.TestGraphsTab.Title = 'Test Graphs';
            app.CSVLINESpinnerLabel = uilabel(app.UIFigure);
            app.CSVLINESpinnerLabel.Text = 'CSV LINE';
            app.CSVLINESpinner = uispinner(app.UIFigure);
            app.CSVLINESpinner.ValueChangingFcn = createCallbackFcn(app, @CSVLINESpinnerValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Track')
            xlabel(app.UIAxes, 'Lat')
            ylabel(app.UIAxes, 'Long')
            app.LapDropDownLabel = uilabel(app.UIFigure);
            app.LapDropDownLabel.Text = 'Lap';
            app.LapDropDown = uidropdown(app.UIFigure);
            app.LapDropDown.Items = {'Lap 1', 'Lap 2', 'Lap 3', 'Lap 4'};
            app.LapDropDown.Editable = 'on';
            app.LapDropDown.Value = 'Lap 1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TrackDataApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end